classdef leafDetc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BackButton                     matlab.ui.control.Button
        LeafdetectionofinfectionLabel  matlab.ui.control.Label
        PourcentageofHealthLabel       matlab.ui.control.Label
        MaskedImageLabel               matlab.ui.control.Label
        CheckleafButton                matlab.ui.control.Button
        ImportedImageLabel             matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ImageInput                     matlab.ui.control.Image
        ImportImageButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
   
    % Open file dialog to select an image
    [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Select an image');

    % Check if the user clicked 'Cancel'
    if isequal(filename, 0) || isequal(pathname, 0)
        disp('No image selected.');
        return;
    end
     % Read the selected image
    imagePath = fullfile(pathname, filename);
    leafImage= imread(imagePath);

leafImage = imresize(leafImage, [256, 256]); % Resize the image if needed
leafImage = im2double(leafImage); % Convert image to double precision

%
app.ImageInput.ImageSource = leafImage;
 app.ImportedImageLabel.Text=('Imported Image');  

        end

        % Button pushed function: CheckleafButton
        function CheckleafButtonPushed(app, event)
 % Retrieve the leaf image from the app's properties
    leafImage = app.ImageInput.ImageSource;
    
    % Check if the leafImage variable is empty or not
    if isempty(leafImage)
        disp('No image selected.');
        return;
    end

[BW, maskedImage] = segmentImage2(leafImage);

% Convert the binary mask to a color image


% Display the image in the app.imageInput component


app.Image.ImageSource = maskedImage;
app.MaskedImageLabel.Text=('Masked Image ');


 % Extract color information from the masked image
redChannel = maskedImage(:, :, 1);
greenChannel = maskedImage(:, :, 2);
blueChannel = maskedImage(:, :, 3);

% Calculate the percentage of green shade dominating in the leaf
totalPixels = numel(greenChannel);
greenPixels = greenChannel > redChannel & greenChannel > blueChannel;
greenPercentage = (sum(greenPixels(:)) / totalPixels) * 100;

% Set the threshold for green dominance (adjust as per your requirements)
greenThreshold = 60;
darkSpotThreshold = 0.7; % Adjust as per your requirements
if greenPercentage >= greenThreshold
    % Leaf is predominantly green, considered healthy
    app.PourcentageofHealthLabel.Text ='Leaf appears healthy.';
else
    % Leaf is not predominantly green
    % Analyze intensity of each channel to differentiate healthy from infected leaves
    redIntensity = mean(redChannel(:));
    greenIntensity = mean(greenChannel(:));
    blueIntensity = mean(blueChannel(:));
if redIntensity < darkSpotThreshold || greenIntensity < darkSpotThreshold
    % Leaf is predominantly green, considered healthy
 infectionPixels = ~greenPixels & blueChannel < redChannel & blueChannel < greenChannel;
        infectionPercentage = (sum(infectionPixels(:)) / totalPixels) * 100;
        app.LeafdetectionofinfectionLabel.Text='<html>Masked Image with Potentially<br> Infected Regions Will Be Highlighted in Red</html>';
      app.PourcentageofHealthLabel.Text = ['Percentage of Infection: ' num2str(infectionPercentage) '%'];

    % Update the masked image to highlight potentially infected regions in red
        maskedImage(:, :, 1) = maskedImage(:, :, 1) + double(infectionPixels);
    blueIntensity = mean(blueChannel(:));
    
    else
        % Leaf is healthy
        app.PourcentageofHealthLabel.Text ='Leaf appears healthy.';
    end


% Display the updated masked image with potentially infected regions highlighted in red
app.Image2.ImageSource=maskedImage;
end      
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            menu
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.ImageInput = uiimage(app.UIFigure);
            app.ImageInput.Tag = 'imageInput';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.ImportedImageLabel = uilabel(app.UIFigure);
            app.ImportedImageLabel.Text = 'Imported Image';
            app.CheckleafButton = uibutton(app.UIFigure, 'push');
            app.CheckleafButton.ButtonPushedFcn = createCallbackFcn(app, @CheckleafButtonPushed, true);
            app.CheckleafButton.Text = 'Check leaf';
            app.MaskedImageLabel = uilabel(app.UIFigure);
            app.MaskedImageLabel.Text = 'Masked Image';
            app.PourcentageofHealthLabel = uilabel(app.UIFigure);
            app.PourcentageofHealthLabel.Text = 'Pourcentage of Health';
            app.LeafdetectionofinfectionLabel = uilabel(app.UIFigure);
            app.LeafdetectionofinfectionLabel.Interpreter = 'html';
            app.LeafdetectionofinfectionLabel.Text = 'Leaf detection of infection ';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = leafDetc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end